classdef GraphicApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TESTARIMAGEMDESENHADAPanel     matlab.ui.container.Panel
        CarregarImagemButton           matlab.ui.control.Button
        DesenharManualmentesfuncionacomwindowsButton  matlab.ui.control.Button
        CarregarRedeButton             matlab.ui.control.Button
        outputInformation              matlab.ui.control.TextArea
        OutputLabel                    matlab.ui.control.Label
        REDEPanel                      matlab.ui.container.Panel
        nRepsEditField                 matlab.ui.control.NumericEditField
        NRepsEditFieldLabel            matlab.ui.control.Label
        PastadeimagensparatreinoPanel  matlab.ui.container.Panel
        pastaImagensDropDown           matlab.ui.control.DropDown
        PastadeimagensDropDownLabel    matlab.ui.control.Label
        divisionParametersPanel        matlab.ui.container.Panel
        testEditField                  matlab.ui.control.NumericEditField
        TesteEditFieldLabel            matlab.ui.control.Label
        valEditField                   matlab.ui.control.NumericEditField
        ValidaoEditFieldLabel          matlab.ui.control.Label
        trainEditField                 matlab.ui.control.NumericEditField
        TreinoEditFieldLabel           matlab.ui.control.Label
        TreinarRedeButton              matlab.ui.control.Button
        FunesPanel                     matlab.ui.container.Panel
        funcAtivacaoDropDown           matlab.ui.control.DropDown
        FunodeAtivaoDropDown_2Label    matlab.ui.control.Label
        funcTreinoDropDown             matlab.ui.control.DropDown
        FunodeTreinoDropDown_2Label    matlab.ui.control.Label
        DadosPanel                     matlab.ui.container.Panel
        numEpochsField                 matlab.ui.control.NumericEditField
        pocasEditField_2Label          matlab.ui.control.Label
        topologiaField                 matlab.ui.control.EditField
        TopologiaEditField_2Label      matlab.ui.control.Label
        SalvarRedeButton               matlab.ui.control.Button
    end

    properties (Access = private)
        redeTreinada % Guardar a rede Treinada
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TreinarRedeButton
        function TreinarRedeButtonPushed(app, event)
            try
                % 1. Obter parâmetros
                topologia = str2num(app.topologiaField.Value);
                if isempty(topologia)
                    app.outputInformation.Value = 'Erro: Topologia inválida.';
                    return;
                end
                numEpochs = app.numEpochsField.Value;
                funcTreino = app.funcTreinoDropDown.Value;
                funcAtivacao = app.funcAtivacaoDropDown.Value;
                trainRatio = app.trainEditField.Value;
                valRatio = app.valEditField.Value;
                testRatio = app.testEditField.Value;
                nReps = app.nRepsEditField.Value;

                fprintf('Divisão dasdasdwasd dos dados:\nTrain: %.1f%%\nVal: %.1f%%\nTest: %.1f%%\n', ...
                            trainRatio*100, ...
                            valRatio*100, ...
                            testRatio*100); %testes de merda       
            
                % 2. Validar parâmetros
                if abs((trainRatio + valRatio + testRatio) - 1) > 1e-4
                    app.outputInformation.Value = 'Parâmetros de divisão incorretos. A soma deve ser 1.';
                    return;
                end
            
                % 3. Carregar imagens
                switch app.pastaImagensDropDown.Value
                    case "Start"
                        [inputs, targets] = convertingStartImagesToBinaryMatrix();
                    case "Train"
                        [inputs, targets] = convertingTrainImagesToBinaryMatrix();
                    case "Test"
                        [inputs, targets] = convertingTestImagesToBinaryMatrix();
                    case "All"
                        [inputs, targets] = convertingAllImagesToBinaryMatrix();
                end
            
                % 4. Inicializar variáveis
                accGlobalVec = zeros(1, nReps);
                accTesteVec = zeros(1, nReps);
                nets = cell(1, nReps);
                trStructs = cell(1, nReps);
            
                app.outputInformation.Value = sprintf(['Iniciando treino com %d repetições e os seguintes parametros:\n ' ...
                    '- Topologia: %s\n' ...
                    '- Número de Épocas: %d\n' ...
                    '- Função de Treino: %s\n' ...
                    '- Função de Ativação: %s\n' ...
                    '- Parametros de Divisão: %.2f %.2f %.2f\n' ...
                    '- Pasta de Imagens: %s\n\n'], nReps, app.topologiaField.Value, numEpochs, funcTreino, funcAtivacao, trainRatio, valRatio, testRatio, app.pastaImagensDropDown.Value);
                drawnow;
            
                % 5. Loop principal (executa exatamente nReps vezes)
                for rep = 1:nReps
                    % Configurar rede
                    net = feedforwardnet(topologia, funcTreino);
                    for i = 1:length(net.layers)-1
                        net.layers{i}.transferFcn = funcAtivacao;
                    end
                    net.layers{end}.transferFcn = 'purelin';  % saída clássica

            
                    net.trainParam.epochs = numEpochs;
                    net.divideParam.trainRatio = trainRatio;
                    net.divideParam.valRatio = valRatio;
                    net.divideParam.testRatio = testRatio;

                    fprintf('Divisão dos dados:\nTrain: %.1f%%\nVal: %.1f%%\nTest: %.1f%%\n', ...
                            net.divideParam.trainRatio*100, ...
                            net.divideParam.valRatio*100, ...
                            net.divideParam.testRatio*100); %testes de merda               
                    
                    % Treinar (única execução do treino)
                    [net, tr] = train(net, inputs, targets);
            
                    % Avaliar
                    outputs = net(inputs);
                    [~, predGlobal] = max(outputs);
                    [~, trueGlobal] = max(targets);
                    accGlobalVec(rep) = sum(predGlobal == trueGlobal)/length(trueGlobal);

                    testInputs = inputs(:, tr.testInd);
                    testTargets = targets(:, tr.testInd);        
                    testOutputs = net(testInputs);
                    [~, predTest] = max(testOutputs);
                    [~, trueTest] = max(testTargets);
                    accTesteVec(rep) = sum(predTest == trueTest)/length(trueTest);
            
                    % Armazenar resultados
                    nets{rep} = net;
                    trStructs{rep} = tr;
            
                    % Mostrar progresso
                    app.outputInformation.Value = [app.outputInformation.Value; 
                        sprintf('Repetição %d/%d\nGlobal: %.2f%% | Teste: %.2f%%\n',...
                        rep, nReps, accGlobalVec(rep)*100, accTesteVec(rep)*100)];
                    drawnow;
                end
            
                % 6. Selecionar melhor rede
                [~, bestRep] = max(accTesteVec);
                bestNet = nets{bestRep};
                bestTr = trStructs{bestRep};
                app.redeTreinada = bestNet;
            
                % 7. Resultados finais
                if nReps > 1
                    finalResults = sprintf(['\nMédias (%d repetições):\n' ...
                                         'Global: %.2f%% (±%.2f)\n' ...
                                         'Teste: %.2f%% (±%.2f)\n' ...
                                         'Melhor: %d (Teste: %.2f%%)'], ...
                                         nReps, mean(accGlobalVec)*100, std(accGlobalVec)*100,...
                                         mean(accTesteVec)*100, std(accTesteVec)*100,...
                                         bestRep, accTesteVec(bestRep)*100);
                else
                    finalResults = sprintf('\nResultado final:\nGlobal: %.2f%%\nTeste: %.2f%%',...
                                         accGlobalVec(1)*100, accTesteVec(1)*100);
                end
                app.outputInformation.Value = [app.outputInformation.Value; finalResults];
            
                % 8. Matriz de confusão
                figure;
                testInputs = inputs(:, bestTr.testInd);
                testTargets = targets(:, bestTr.testInd);
                testOutputs = bestNet(testInputs);
                plotconfusion(testTargets, testOutputs);
                title(sprintf('Matriz Confusão - [%s] (Rep %d/%d)',...
                             num2str(topologia), bestRep, nReps));
            
            catch ME
                app.outputInformation.Value = ['Erro ao treinar: ', ME.message];
            end
        end

        % Button pushed function: SalvarRedeButton
        function SalvarRedeButtonPushed(app, event)
            try
                if isempty(app.redeTreinada)
                    app.outputInformation.Value = "Erro: Nenhuma rede treinada para gravar.";
                    return;
                end
        
                [file, path] = uiputfile('redeTreinada.mat', 'Guardar Rede Treinada');
                if isequal(file, 0)
                    app.outputInformation.Value = "Gravação cancelada.";
                    return;
                end
        
                net = app.redeTreinada; 
                save(fullfile(path, file), 'net');
                app.outputInformation.Value = sprintf("Rede guardada em: %s", fullfile(path, file));
            catch ME
                app.outputInformation.Value = sprintf("Erro ao gravar: %s", ME.message);
            end
        end

        % Button pushed function: CarregarRedeButton
        function CarregarRedeButtonPushed(app, event)
            try
                [file, path] = uigetfile('*.mat', 'Selecionar Ficheiro de Rede');
                if isequal(file, 0)
                    app.outputInformation.Value = "Carregamento cancelado.";
                    return;
                end
        
                data = load(fullfile(path, file));
                
                if isfield(data, 'net')
                    app.redeTreinada = data.net;
                    app.outputInformation.Value = sprintf("Rede carregada com sucesso de: %s", fullfile(path, file));
                else
                    app.outputInformation.Value = "Erro: O ficheiro não contém uma rede válida.";
                end
            catch ME
                app.outputInformation.Value = sprintf("Erro ao carregar rede: %s", ME.message);
            end
        end

        % Button pushed function: CarregarImagemButton
        function CarregarImagemButtonPushed(app, event)
            try
                if isempty(app.redeTreinada)
                    app.outputInformation.Value = "Erro: Nenhuma rede carregada ou treinada.";
                    return;
                end
                
                [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp'}, 'Selecionar Imagem');
                if isequal(file, 0)
                    app.outputInformation.Value = "Seleção de imagem cancelada.";
                    return;
                end
                
                % Carregar e converter a imagem
                imgPath = fullfile(path, file);
                img = imread(imgPath);
                if size(img, 3) == 3
                    img = rgb2gray(img);
                end
                img = imbinarize(imresize(img, [25 25])); % Redimensionar se necessário
                imgVect = double(img(:)); % Converter para vetor coluna
                
                % Aplicar rede
                output = app.redeTreinada(imgVect);
                [confidence, predictedClass] = max(output);
                classes = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};
                
                app.outputInformation.Value = sprintf(...
                    "Classe prevista: %s (Confiança: %.2f%%)", ...
                    classes{predictedClass}, confidence * 100);
            catch ME
                app.outputInformation.Value = sprintf("Erro ao testar imagem: %s", ME.message);
            end
        end

        % Button pushed function: 
        % DesenharManualmentesfuncionacomwindowsButton
        function DesenharManualmentesfuncionacomwindowsButtonPushed(app, event)
            try
                % Criar imagem em branco 224x224 (RGB branco)
                img = uint8(255*ones(224, 224, 3));
                
                % Salvar como arquivo temporário
                tempFile = [tempname '.png'];
                imwrite(img, tempFile);
                
                % Abrir no Paint
                system(['start mspaint.exe "' tempFile '"']);
                
                % Mensagem de confirmação
                app.outputInformation.Value = 'Paint aberto com imagem 224x224';
                
                % Opcional: deletar o arquivo temporário depois (adicionar timer)
                pause(5); % Espera 5 segundos antes de deletar
                if exist(tempFile, 'file')
                    delete(tempFile);
                end
                
            catch ME
                app.outputInformation.Value = ['Erro: ' ME.message];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.REDEPanel = uipanel(app.UIFigure);
            app.REDEPanel.Title = 'REDE';
            app.SalvarRedeButton = uibutton(app.REDEPanel, 'push');
            app.SalvarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarRedeButtonPushed, true);
            app.SalvarRedeButton.Text = 'Salvar Rede';
            app.DadosPanel = uipanel(app.REDEPanel);
            app.DadosPanel.Title = 'Dados';
            app.TopologiaEditField_2Label = uilabel(app.DadosPanel);
            app.TopologiaEditField_2Label.Text = 'Topologia';
            app.topologiaField = uieditfield(app.DadosPanel, 'text');
            app.topologiaField.Value = '10';
            app.pocasEditField_2Label = uilabel(app.DadosPanel);
            app.pocasEditField_2Label.Text = 'Épocas';
            app.numEpochsField = uieditfield(app.DadosPanel, 'numeric');
            app.numEpochsField.Value = 20;
            app.FunesPanel = uipanel(app.REDEPanel);
            app.FunesPanel.Title = 'Funções';
            app.FunodeTreinoDropDown_2Label = uilabel(app.FunesPanel);
            app.FunodeTreinoDropDown_2Label.Text = 'Função de Treino';
            app.funcTreinoDropDown = uidropdown(app.FunesPanel);
            app.funcTreinoDropDown.Items = {'trainlm', 'traingd', 'trainscg', 'trainrp'};
            app.funcTreinoDropDown.Value = 'trainlm';
            app.FunodeAtivaoDropDown_2Label = uilabel(app.FunesPanel);
            app.FunodeAtivaoDropDown_2Label.Text = 'Função de Ativação';
            app.funcAtivacaoDropDown = uidropdown(app.FunesPanel);
            app.funcAtivacaoDropDown.Items = {'logsig', 'tansig', 'purelin', 'softmax'};
            app.funcAtivacaoDropDown.Value = 'tansig';
            app.TreinarRedeButton = uibutton(app.REDEPanel, 'push');
            app.TreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedeButtonPushed, true);
            app.TreinarRedeButton.Text = 'Treinar Rede';
            app.divisionParametersPanel = uipanel(app.REDEPanel);
            app.divisionParametersPanel.Title = 'Parametros de Divisão';
            app.TreinoEditFieldLabel = uilabel(app.divisionParametersPanel);
            app.TreinoEditFieldLabel.Text = 'Treino';
            app.trainEditField = uieditfield(app.divisionParametersPanel, 'numeric');
            app.trainEditField.Limits = [0 1];
            app.trainEditField.Value = 0.7;
            app.ValidaoEditFieldLabel = uilabel(app.divisionParametersPanel);
            app.ValidaoEditFieldLabel.Text = 'Validação';
            app.valEditField = uieditfield(app.divisionParametersPanel, 'numeric');
            app.valEditField.Limits = [0 1];
            app.valEditField.Value = 0.15;
            app.TesteEditFieldLabel = uilabel(app.divisionParametersPanel);
            app.TesteEditFieldLabel.Text = 'Teste';
            app.testEditField = uieditfield(app.divisionParametersPanel, 'numeric');
            app.testEditField.Limits = [0 1];
            app.testEditField.Value = 0.15;
            app.PastadeimagensparatreinoPanel = uipanel(app.REDEPanel);
            app.PastadeimagensparatreinoPanel.Title = 'Pasta de imagens para treino';
            app.PastadeimagensDropDownLabel = uilabel(app.PastadeimagensparatreinoPanel);
            app.PastadeimagensDropDownLabel.Text = 'Pasta de imagens';
            app.pastaImagensDropDown = uidropdown(app.PastadeimagensparatreinoPanel);
            app.pastaImagensDropDown.Items = {'Train', 'Start', 'Test', 'All'};
            app.pastaImagensDropDown.Value = 'Train';
            app.NRepsEditFieldLabel = uilabel(app.REDEPanel);
            app.NRepsEditFieldLabel.Text = 'Nº Reps';
            app.nRepsEditField = uieditfield(app.REDEPanel, 'numeric');
            app.nRepsEditField.Limits = [1 Inf];
            app.nRepsEditField.Value = 5;
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.outputInformation = uitextarea(app.UIFigure);
            app.TESTARIMAGEMDESENHADAPanel = uipanel(app.UIFigure);
            app.TESTARIMAGEMDESENHADAPanel.Title = 'TESTAR IMAGEM DESENHADA';
            app.CarregarRedeButton = uibutton(app.TESTARIMAGEMDESENHADAPanel, 'push');
            app.CarregarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarRedeButtonPushed, true);
            app.CarregarRedeButton.Text = 'Carregar Rede';
            app.DesenharManualmentesfuncionacomwindowsButton = uibutton(app.TESTARIMAGEMDESENHADAPanel, 'push');
            app.DesenharManualmentesfuncionacomwindowsButton.ButtonPushedFcn = createCallbackFcn(app, @DesenharManualmentesfuncionacomwindowsButtonPushed, true);
            app.DesenharManualmentesfuncionacomwindowsButton.Text = {'Desenhar Manualmente'; '(só funciona com windows)'};
            app.CarregarImagemButton = uibutton(app.TESTARIMAGEMDESENHADAPanel, 'push');
            app.CarregarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarImagemButtonPushed, true);
            app.CarregarImagemButton.Text = 'Carregar Imagem';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphicApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end